classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        simulationButton     matlab.ui.control.Button
        link2EditField       matlab.ui.control.NumericEditField
        link2EditFieldLabel  matlab.ui.control.Label
        link1EditField       matlab.ui.control.NumericEditField
        link1EditFieldLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: simulationButton
        function simulationButtonPushed(app, event)
hold(app.UIAxes,"off");
%the inputs by the user 
link1=app.link1EditField.Value;
 
link2=app.link2EditField.Value;
%the angles 
theta1=linspace(0,360,800);
theta2=linspace(0,360,800);


%the value of x1,y1 and x2,y2  
x1= link1*cosd(theta1);                                                     %now it is a matrix of 100 element 
y1=link1*sind(theta1);

x2=x1+link2*cosd(theta1+theta2);
y2=y1+link2*sind(theta1+theta2);

%create a variable to delete the last iteration
%p=plot([0,x1(i)],[0,y1(i)],[x1(i),x2(i)],[y1(i),y2(i)]);                   %no difference from the below way
p1=plot(app.UIAxes,[0,x1(1)],[0,y1(1)],'g',LineWidth=2);
hold(app.UIAxes,"on");

p2=plot(app.UIAxes,[x1(1),x2(1)],[y1(1),y2(1)],'b',LineWidth=3);

xlim([-(link1+link2)*1.5,(link1+link2)*1.5]);                                                   %add limits for the frame on x-axis
ylim([-(link1+link2)*1.5,(link1+link2)*1.5]);                                               %add limits for the frame on y-axis
hold on
grid on

plot(app.UIAxes,x2,y2);                                                                %plot the final curve path 
%to plot the different angles 
for i=1:length(theta2)
    delete(p1);
    delete(p2);
    %p=plot([0,x1(i)],[0,y1(i)],[x1(i),x2(i)],[y1(i),y2(i)]);               %no difference from the below way
    p1=plot(app.UIAxes,[0,x1(i)],[0,y1(i)],'g',LineWidth=2);
    p2=plot(app.UIAxes,[x1(i),x2(i)],[y1(i),y2(i)],'b',LineWidth=3);
    drawnow                                                                 %to draw the animation at same time of the loop
    %pause(0.005)                                                             %%to control the speed make it quite lower 
end 


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Simulation double-pendulum')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.link1EditFieldLabel = uilabel(app.UIFigure);
            app.link1EditFieldLabel.Text = 'link1';
            app.link1EditField = uieditfield(app.UIFigure, 'numeric');
            app.link1EditField.Limits = [0 20];
            app.link2EditFieldLabel = uilabel(app.UIFigure);
            app.link2EditFieldLabel.Text = 'link2';
            app.link2EditField = uieditfield(app.UIFigure, 'numeric');
            app.link2EditField.Limits = [0 20];
            app.simulationButton = uibutton(app.UIFigure, 'push');
            app.simulationButton.ButtonPushedFcn = createCallbackFcn(app, @simulationButtonPushed, true);
            app.simulationButton.Text = 'simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end